classdef Application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        FeederInputTab                  matlab.ui.container.Tab
        ConductorCAmpacityEditField     matlab.ui.control.NumericEditField
        ConductorBAmpacityEditField     matlab.ui.control.NumericEditField
        ConductorAAmpacityEditField     matlab.ui.control.NumericEditField
        ConductorCREditField            matlab.ui.control.NumericEditField
        ConductorCEditFieldLabel        matlab.ui.control.Label
        ConductorCXEditField            matlab.ui.control.NumericEditField
        ConductorBXEditField            matlab.ui.control.NumericEditField
        ConductorBREditField            matlab.ui.control.NumericEditField
        ConductorBEditFieldLabel        matlab.ui.control.Label
        ConductorAXEditField            matlab.ui.control.NumericEditField
        ConductorAREditField            matlab.ui.control.NumericEditField
        ConductorAEditFieldLabel        matlab.ui.control.Label
        XohmkmLabel                     matlab.ui.control.Label
        RohmkmLabel                     matlab.ui.control.Label
        AmpacityALabel                  matlab.ui.control.Label
        ConductorParametersLabel        matlab.ui.control.Label
        ANALYZEFEEDERButton             matlab.ui.control.Button
        SectionLengthkmEditFieldLabel   matlab.ui.control.Label
        Load4kWEditField                matlab.ui.control.NumericEditField
        Load4kWEditFieldLabel           matlab.ui.control.Label
        Load3kWEditField                matlab.ui.control.NumericEditField
        Load3kWEditFieldLabel           matlab.ui.control.Label
        Load2kWEditField                matlab.ui.control.NumericEditField
        Load2kWEditFieldLabel           matlab.ui.control.Label
        Load1kWEditField                matlab.ui.control.NumericEditField
        Load1kWEditFieldLabel           matlab.ui.control.Label
        SectionLengthkmEditField        matlab.ui.control.NumericEditField
        PowerFactorEditField            matlab.ui.control.NumericEditField
        PowerFactorEditFieldLabel       matlab.ui.control.Label
        SystemVoltagekVEditField        matlab.ui.control.NumericEditField
        SystemVoltagekvEditFieldLabel   matlab.ui.control.Label
        INPUTPARAMETERSLabel            matlab.ui.control.Label
        kVDISTRIBUTIONFEEDERANALYSISDESIGNTOOLTitleLabel_2  matlab.ui.control.Label
        AnalysisResultsTab              matlab.ui.container.Tab
        PerformanceSummaryPanel         matlab.ui.container.Panel
        ConductorStatusEditField        matlab.ui.control.EditField
        ConductorStatusEditFieldLabel   matlab.ui.control.Label
        RecommendedConductorEditField   matlab.ui.control.EditField
        RecommendedConductorEditFieldLabel  matlab.ui.control.Label
        TotalPowerLosskWEditField       matlab.ui.control.NumericEditField
        TotalPowerLosskWEditFieldLabel  matlab.ui.control.Label
        VoltageRegulationEditField      matlab.ui.control.NumericEditField
        VoltageRegulationLabel          matlab.ui.control.Label
        MinimumVoltagekVEditField       matlab.ui.control.NumericEditField
        MinimumVoltagekVEditFieldLabel  matlab.ui.control.Label
        MaximumCurrentAEditField        matlab.ui.control.NumericEditField
        MaximumCurrentAEditFieldLabel   matlab.ui.control.Label
        TotalLoadkWEditField            matlab.ui.control.NumericEditField
        TotalLoadkWEditFieldLabel       matlab.ui.control.Label
        kVFEEDERANALYSISRESULTSLabel    matlab.ui.control.Label
        PowerLossAxes                   matlab.ui.control.UIAxes
        VoltageProfileAxes              matlab.ui.control.UIAxes
        ConductorEvaluationTab          matlab.ui.container.Tab
        ConductorEvaluationTable        matlab.ui.control.Table
        ReportTab                       matlab.ui.container.Tab
        PerformanceSummaryLabel         matlab.ui.control.Label
        ConductorEvaluationLabel        matlab.ui.control.Label
        ReportConductorEvaluationTable  matlab.ui.control.Table
        kVFeederReportLabel             matlab.ui.control.Label
        PerformanceSummaryTable         matlab.ui.control.Table
        DownloadReportButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ANALYZEFEEDERButton
        function ANALYZEFEEDERButtonPushed(app, event)
            % Read input values from the app
            % Input validation

if app.SystemVoltagekVEditField.Value <= 0
    uialert(app.UIFigure, ...
        'System voltage must be greater than 0 kV.', ...
        'Invalid Input');
    return;
end

if app.PowerFactorEditField.Value <= 0 || ...
        app.PowerFactorEditField.Value > 1
    uialert(app.UIFigure, ...
        'Power factor must be between 0 and 1.', ...
        'Invalid Input');
    return;
end
if any([ ...
        app.Load1kWEditField.Value, ...
        app.Load2kWEditField.Value, ...
        app.Load3kWEditField.Value, ...
        app.Load4kWEditField.Value] < 0)

    uialert(app.UIFigure, ...
        'Load values cannot be negative.', ...
        'Invalid Input');
    return;
end
if app.SectionLengthkmEditField.Value <= 0
    uialert(app.UIFigure, ...
        'Section length must be greater than 0 km.', ...
        'Invalid Input');
    return;
end
V_LL = app.SystemVoltagekVEditField.Value * 1000;
pf = app.PowerFactorEditField.Value;

load_power = [ ...
    app.Load1kWEditField.Value, ...
    app.Load2kWEditField.Value, ...
    app.Load3kWEditField.Value, ...
    app.Load4kWEditField.Value];

section_length = app.SectionLengthkmEditField.Value * ones(1,4);
% Design limit
maxVoltageRegulation = 5;

% Read user-defined conductor parameters

conductorNames = {'Conductor A', 'Conductor B', 'Conductor C'};

R_values = [
    app.ConductorAREditField.Value
    app.ConductorBREditField.Value
    app.ConductorCREditField.Value
];

X_values = [
    app.ConductorAXEditField.Value
    app.ConductorBXEditField.Value
    app.ConductorCXEditField.Value
];

ampacity_values = [
    app.ConductorAAmpacityEditField.Value
    app.ConductorBAmpacityEditField.Value
    app.ConductorCAmpacityEditField.Value
];
% Validate conductor parameters

if any(R_values <= 0) || any(X_values <= 0)
    uialert(app.UIFigure, ...
        'Resistance and reactance must be greater than zero.', ...
        'Invalid Conductor Data');
    return;
end

if any(ampacity_values <= 0)
    uialert(app.UIFigure, ...
        'Conductor ampacity must be greater than zero.', ...
        'Invalid Conductor Data');
    return;
end

% Power factor angle
sin_phi = sqrt(1 - pf^2);

% Downstream load
downstream_power = zeros(1,4);

downstream_power(1) = sum(load_power);
downstream_power(2) = sum(load_power(2:4));
downstream_power(3) = sum(load_power(3:4));
downstream_power(4) = load_power(4);

% Feeder current
current = (downstream_power * 1000) ./ ...
    (sqrt(3) * V_LL * pf);

% Maximum feeder current
maximum_current = max(current);
% =========================================================
% CONDUCTOR EVALUATION TABLE
% =========================================================

evaluationData = cell(3,5);

for k = 1:3

    % Conductor information
    evaluationData{k,1} = conductorNames{k};
    evaluationData{k,2} = ampacity_values(k);

    % Maximum feeder current
    evaluationData{k,3} = maximum_current;

    % Calculate voltage drop for this conductor
    voltage_drop_eval = sqrt(3) .* current .* ...
        (R_values(k) * pf + X_values(k) * sin_phi) .* ...
        section_length;

    cumulative_drop_eval = cumsum(voltage_drop_eval);

    voltage_eval = V_LL - cumulative_drop_eval;

    minimum_voltage_eval = min(voltage_eval);

    regulation_eval = ...
        ((V_LL - minimum_voltage_eval) / V_LL) * 100;

    evaluationData{k,4} = regulation_eval;

    % Check conductor suitability
    if maximum_current <= ampacity_values(k) && ...
            regulation_eval <= maxVoltageRegulation

        evaluationData{k,5} = 'Suitable';

    elseif maximum_current > ampacity_values(k)

        evaluationData{k,5} = 'Overloaded';

    else

        evaluationData{k,5} = 'High Voltage Drop';

    end

end

% Display results in table
app.ConductorEvaluationTable.Data = evaluationData;
% Automatic conductor evaluation

recommendedConductor = 'No suitable conductor';
selectedIndex = 0;

for k = 1:length(conductorNames)

    R_test = R_values(k);
    X_test = X_values(k);
    ampacity_test = ampacity_values(k);

    voltage_drop_test = sqrt(3) .* current .* ...
        (R_test * pf + X_test * sin_phi) .* ...
        section_length;

    cumulative_drop_test = cumsum(voltage_drop_test);

    voltage_test = V_LL - cumulative_drop_test;

    minimum_voltage_test = min(voltage_test);

    regulation_test = ...
        ((V_LL - minimum_voltage_test) / V_LL) * 100;

    current_ok = maximum_current <= ampacity_test;

    voltage_ok = regulation_test <= maxVoltageRegulation;

    if current_ok && voltage_ok
        recommendedConductor = conductorNames{k};
        selectedIndex = k;
        break;
    end

end
% Check whether a suitable conductor was found

if selectedIndex == 0

    conductorStatus = 'No Suitable Conductor';

    uialert(app.UIFigure, ...
        'No conductor satisfies both ampacity and voltage regulation limits.', ...
        'Conductor Selection');

    return;

else

    % Use the selected conductor parameters
    R = R_values(selectedIndex);
    X = X_values(selectedIndex);
    ampacity = ampacity_values(selectedIndex);

    % Select recommended conductor parameters

selectedIndex = find(strcmp(conductorNames, recommendedConductor), 1);

if isempty(selectedIndex)

    conductorStatus = 'No Suitable Conductor';

    % Use Conductor A for displaying electrical results
    selectedIndex = 1;

else

    conductorStatus = 'Suitable';

end

R = R_values(selectedIndex);
X = X_values(selectedIndex);
ampacity = ampacity_values(selectedIndex);

% Voltage drop using selected conductor

voltage_drop = sqrt(3) .* current .* ...
    (R * pf + X * sin_phi) .* section_length;

% Voltage profile

cumulative_drop = cumsum(voltage_drop);

voltage_at_pole = V_LL - cumulative_drop;

% Voltage regulation

minimum_voltage = min(voltage_at_pole);

voltage_regulation = ...
    ((V_LL - minimum_voltage) / V_LL) * 100;

% Power losses

power_loss = 3 .* (current.^2) .* ...
    R .* section_length;

total_power_loss = sum(power_loss);


% Conductor evaluation table

evaluationData = cell(3,5);

for k = 1:3

    R_test = R_values(k);
    X_test = X_values(k);
    ampacity_test = ampacity_values(k);

    voltage_drop_test = sqrt(3) .* current .* ...
        (R_test * pf + X_test * sin_phi) .* ...
        section_length;

    cumulative_drop_test = cumsum(voltage_drop_test);

    voltage_test = V_LL - cumulative_drop_test;

    minimum_voltage_test = min(voltage_test);

    regulation_test = ...
        ((V_LL - minimum_voltage_test) / V_LL) * 100;

    current_ok = maximum_current <= ampacity_test;

    voltage_ok = regulation_test <= maxVoltageRegulation;

    if current_ok && voltage_ok
        status = 'PASS';
    else
        status = 'FAIL';
    end

    evaluationData{k,1} = conductorNames{k};
    evaluationData{k,2} = ampacity_test;
    evaluationData{k,3} = maximum_current;
    evaluationData{k,4} = regulation_test;
    evaluationData{k,5} = status;

end

app.ConductorEvaluationTable.Data = evaluationData;

% Display results
app.TotalLoadkWEditField.Value = sum(load_power);

app.MaximumCurrentAEditField.Value = max(current);

app.MinimumVoltagekVEditField.Value = ...
    minimum_voltage / 1000;

app.VoltageRegulationEditField.Value = ...
    voltage_regulation;

app.TotalPowerLosskWEditField.Value = ...
    total_power_loss / 1000;

% Recommended conductor
app.RecommendedConductorEditField.Value = ...
    recommendedConductor;
app.ConductorStatusEditField.Value = conductorStatus;
% Update Performance Summary Report

reportData = {
    'Total Load (kW)', sum(load_power);
    'Maximum Current (A)', maximum_current;
    'Minimum Voltage (kV)', minimum_voltage / 1000;
    'Voltage Regulation (%)', voltage_regulation;
    'Total Power Loss (kW)', total_power_loss / 1000;
    'Recommended Conductor', recommendedConductor;
    'Conductor Status', conductorStatus
};

app.PerformanceSummaryTable.Data = reportData;

% Update Conductor Evaluation Report
app.ReportConductorEvaluationTable.Data = evaluationData;

app.TabGroup.SelectedTab = app.ReportTab;

% Voltage profile graph
distance = [0 cumsum(section_length)];

voltage_profile = ...
    [V_LL voltage_at_pole] / 1000;

plot(app.VoltageProfileAxes, ...
    distance, voltage_profile, '-o', ...
    'LineWidth', 2);

grid(app.VoltageProfileAxes, 'on');

xlabel(app.VoltageProfileAxes, ...
    'Distance from Substation (km)');

ylabel(app.VoltageProfileAxes, ...
    'Voltage (kV)');

title(app.VoltageProfileAxes, ...
    '11-kV Feeder Voltage Profile');

% Nominal voltage reference
hold(app.VoltageProfileAxes, 'on');

plot(app.VoltageProfileAxes, ...
    distance, ...
    ones(size(distance)) * V_LL/1000, ...
    '--');

hold(app.VoltageProfileAxes, 'off');

% Power loss graph
bar(app.PowerLossAxes, ...
    1:4, power_loss/1000);

grid(app.PowerLossAxes, 'on');

xlabel(app.PowerLossAxes, ...
    'Feeder Section');

ylabel(app.PowerLossAxes, ...
    'Power Loss (kW)');

title(app.PowerLossAxes, ...
    'Power Loss by Feeder Section');

% Move directly to Report tab
app.TabGroup.SelectedTab = app.ReportTab;
end
        end

        % Button pushed function: DownloadReportButton
        function DownloadReportButtonPushed(app, event)
            % Check whether analysis has been completed
if isempty(app.PerformanceSummaryTable.Data)

    uialert(app.UIFigure, ...
        'Please analyze the feeder before downloading the report.', ...
        'Report Not Available');

    return;
end

% Select PDF save location
[file, path] = uiputfile( ...
    '*.pdf', ...
    'Save T&D Feeder Analysis Report As', ...
    'TD_Feeder_Analysis_Report.pdf');

if isequal(file, 0)
    return;
end

pdfFile = fullfile(path, file);

try

    %% READ INPUT DATA

    systemVoltage = app.SystemVoltagekVEditField.Value;
    pf = app.PowerFactorEditField.Value;

    loadPower = [ ...
        app.Load1kWEditField.Value, ...
        app.Load2kWEditField.Value, ...
        app.Load3kWEditField.Value, ...
        app.Load4kWEditField.Value];

    sectionLength = app.SectionLengthkmEditField.Value;

    totalLoad = app.TotalLoadkWEditField.Value;
    maximumCurrent = app.MaximumCurrentAEditField.Value;
    minimumVoltage = app.MinimumVoltagekVEditField.Value;
    voltageRegulation = app.VoltageRegulationEditField.Value;
    totalPowerLoss = app.TotalPowerLosskWEditField.Value;

    recommendedConductor = ...
        app.RecommendedConductorEditField.Value;

    conductorStatus = ...
        app.ConductorStatusEditField.Value;


    %% CONDUCTOR DATA

    conductorNames = { ...
        'Conductor A'; ...
        'Conductor B'; ...
        'Conductor C'};

    ampacity = [ ...
        app.ConductorAAmpacityEditField.Value; ...
        app.ConductorBAmpacityEditField.Value; ...
        app.ConductorCAmpacityEditField.Value];

    R = [ ...
        app.ConductorAREditField.Value; ...
        app.ConductorBREditField.Value; ...
        app.ConductorCREditField.Value];

    X = [ ...
        app.ConductorAXEditField.Value; ...
        app.ConductorBXEditField.Value; ...
        app.ConductorCXEditField.Value];


    %% CALCULATE GRAPH DATA

    V_LL = systemVoltage * 1000;

    sin_phi = sqrt(1 - pf^2);

    downstreamPower = zeros(1,4);

    downstreamPower(1) = sum(loadPower);
    downstreamPower(2) = sum(loadPower(2:4));
    downstreamPower(3) = sum(loadPower(3:4));
    downstreamPower(4) = loadPower(4);

    current = (downstreamPower * 1000) ./ ...
        (sqrt(3) * V_LL * pf);

    selectedIndex = find( ...
        strcmp(conductorNames, recommendedConductor), 1);

    if isempty(selectedIndex)
        selectedIndex = 1;
    end

    R_selected = R(selectedIndex);
    X_selected = X(selectedIndex);

    sectionLengths = sectionLength * ones(1,4);

    voltageDrop = sqrt(3) .* current .* ...
        (R_selected * pf + X_selected * sin_phi) .* ...
        sectionLengths;

    cumulativeDrop = cumsum(voltageDrop);

    voltageAtPole = V_LL - cumulativeDrop;

    distance = [0 cumsum(sectionLengths)];

    voltageProfile = ...
        [V_LL voltageAtPole] / 1000;

    powerLoss = 3 .* (current.^2) .* ...
        R_selected .* sectionLengths;

    powerLosskW = powerLoss / 1000;


    %% PAGE 1 - SUMMARY

    fig = figure( ...
        'Visible','off', ...
        'Color','white', ...
        'Position',[100 100 900 650]);

    axis off;

    text(0.5,0.88, ...
        '11-kV DISTRIBUTION FEEDER', ...
        'HorizontalAlignment','center', ...
        'FontSize',22, ...
        'FontWeight','bold');

    text(0.5,0.82, ...
        'ANALYSIS & DESIGN REPORT', ...
        'HorizontalAlignment','center', ...
        'FontSize',18, ...
        'FontWeight','bold');

    text(0.5,0.75, ...
        'T&D Engineering Analysis Tool', ...
        'HorizontalAlignment','center', ...
        'FontSize',13);

    summaryText = sprintf([ ...
        'SYSTEM VOLTAGE              %.2f kV\n\n' ...
        'TOTAL LOAD                  %.2f kW\n\n' ...
        'MAXIMUM CURRENT             %.2f A\n\n' ...
        'MINIMUM VOLTAGE             %.3f kV\n\n' ...
        'VOLTAGE REGULATION          %.3f %%\n\n' ...
        'TOTAL POWER LOSS            %.3f kW\n\n' ...
        'RECOMMENDED CONDUCTOR       %s\n\n' ...
        'CONDUCTOR STATUS            %s'], ...
        systemVoltage, ...
        totalLoad, ...
        maximumCurrent, ...
        minimumVoltage, ...
        voltageRegulation, ...
        totalPowerLoss, ...
        recommendedConductor, ...
        conductorStatus);

    text(0.5,0.45,summaryText, ...
        'HorizontalAlignment','center', ...
        'VerticalAlignment','middle', ...
        'FontSize',13, ...
        'FontName','Arial');

    text(0.5,0.08, ...
        ['Generated on ' datestr(now,'dd-mmm-yyyy HH:MM')], ...
        'HorizontalAlignment','center', ...
        'FontSize',10);

    exportgraphics(fig,pdfFile, ...
        'ContentType','vector');

    close(fig);


    %% PAGE 2 - INPUT DATA

    fig = figure( ...
        'Visible','off', ...
        'Color','white', ...
        'Position',[100 100 900 650]);

    axis off;

    text(0.5,0.93, ...
        'INPUT PARAMETERS', ...
        'HorizontalAlignment','center', ...
        'FontSize',20, ...
        'FontWeight','bold');

    inputText = sprintf([ ...
        'System Voltage                 %.2f kV\n\n' ...
        'Power Factor                   %.3f\n\n' ...
        'Section Length                 %.3f km\n\n' ...
        'Load 1                         %.2f kW\n' ...
        'Load 2                         %.2f kW\n' ...
        'Load 3                         %.2f kW\n' ...
        'Load 4                         %.2f kW\n\n' ...
        'Total Load                     %.2f kW'], ...
        systemVoltage, ...
        pf, ...
        sectionLength, ...
        loadPower(1), ...
        loadPower(2), ...
        loadPower(3), ...
        loadPower(4), ...
        totalLoad);

    text(0.08,0.82,inputText, ...
        'VerticalAlignment','top', ...
        'FontSize',13, ...
        'FontName','Arial');

    text(0.5,0.43, ...
        'CONDUCTOR PARAMETERS', ...
        'HorizontalAlignment','center', ...
        'FontSize',17, ...
        'FontWeight','bold');

    conductorText = sprintf([ ...
        'Conductor A     Ampacity: %.1f A     R: %.3f     X: %.3f\n\n' ...
        'Conductor B     Ampacity: %.1f A     R: %.3f     X: %.3f\n\n' ...
        'Conductor C     Ampacity: %.1f A     R: %.3f     X: %.3f'], ...
        ampacity(1),R(1),X(1), ...
        ampacity(2),R(2),X(2), ...
        ampacity(3),R(3),X(3));

    text(0.08,0.35,conductorText, ...
        'VerticalAlignment','top', ...
        'FontSize',12, ...
        'FontName','Arial');

    exportgraphics(fig,pdfFile, ...
        'Append',true, ...
        'ContentType','vector');

    close(fig);


    %% PAGE 3 - CONDUCTOR EVALUATION

    fig = figure( ...
        'Visible','off', ...
        'Color','white', ...
        'Position',[100 100 900 650]);

    annotation(fig,'textbox', ...
        [0.2 0.86 0.6 0.08], ...
        'String','CONDUCTOR EVALUATION', ...
        'HorizontalAlignment','center', ...
        'FontSize',20, ...
        'FontWeight','bold', ...
        'EdgeColor','none');

    evaluationData = app.ReportConductorEvaluationTable.Data;

    tableData = cell(size(evaluationData));

    for k = 1:size(evaluationData,1)

        tableData{k,1} = evaluationData{k,1};
        tableData{k,2} = sprintf('%.1f',evaluationData{k,2});
        tableData{k,3} = sprintf('%.2f',evaluationData{k,3});
        tableData{k,4} = sprintf('%.3f',evaluationData{k,4});
        tableData{k,5} = evaluationData{k,5};

    end

    uitable(fig, ...
        'Data',tableData, ...
        'ColumnName',{ ...
        'Conductor', ...
        'Ampacity (A)', ...
        'Max Current (A)', ...
        'Voltage Regulation (%)', ...
        'Status'}, ...
        'RowName',{}, ...
        'Units','normalized', ...
        'Position',[0.08 0.35 0.84 0.38], ...
        'FontSize',11);

    conclusion = sprintf([ ...
        'Recommended Conductor: %s\n' ...
        'Overall Status: %s\n' ...
        'Voltage Regulation Limit: 5 %%'], ...
        recommendedConductor, ...
        conductorStatus);

    annotation(fig,'textbox', ...
        [0.15 0.15 0.70 0.12], ...
        'String',conclusion, ...
        'HorizontalAlignment','center', ...
        'FontSize',13, ...
        'FontWeight','bold', ...
        'EdgeColor','none');

    exportgraphics(fig,pdfFile, ...
        'Append',true, ...
        'ContentType','vector');

    close(fig);


    %% PAGE 4 - VOLTAGE PROFILE

    fig = figure( ...
        'Visible','off', ...
        'Color','white', ...
        'Position',[100 100 900 650]);

    ax = axes(fig);

    plot(ax, ...
        distance, ...
        voltageProfile, ...
        '-o', ...
        'LineWidth',2, ...
        'MarkerSize',6);

    hold(ax,'on');

    plot(ax, ...
        distance, ...
        ones(size(distance))*systemVoltage, ...
        '--', ...
        'LineWidth',1.5);

    hold(ax,'off');

    grid(ax,'on');

    xlabel(ax,'Distance from Substation (km)');
    ylabel(ax,'Voltage (kV)');

    title(ax, ...
        '11-kV Feeder Voltage Profile', ...
        'FontSize',18, ...
        'FontWeight','bold');

    legend(ax, ...
        {'Actual Voltage','Nominal Voltage'}, ...
        'Location','best');

    exportgraphics(fig,pdfFile, ...
        'Append',true, ...
        'ContentType','vector');

    close(fig);


    %% PAGE 5 - POWER LOSS

    fig = figure( ...
        'Visible','off', ...
        'Color','white', ...
        'Position',[100 100 900 650]);

    ax = axes(fig);

    bar(ax,1:4,powerLosskW);

    grid(ax,'on');

    xlabel(ax,'Feeder Section');
    ylabel(ax,'Power Loss (kW)');

    title(ax, ...
        'Power Loss by Feeder Section', ...
        'FontSize',18, ...
        'FontWeight','bold');

    xticks(ax,1:4);

    xticklabels(ax,{ ...
        'Section 1', ...
        'Section 2', ...
        'Section 3', ...
        'Section 4'});

    exportgraphics(fig,pdfFile, ...
        'Append',true, ...
        'ContentType','vector');

    close(fig);


    %% SUCCESS

    uialert(app.UIFigure, ...
        ['PDF report generated successfully.' newline newline ...
         'Saved at:' newline pdfFile], ...
        'Report Generated');

catch ME

    % Close hidden figures if an error occurs
    figs = findall(0,'Type','figure');

    for k = 1:length(figs)

        try

            if strcmp(figs(k).Visible,'off')
                close(figs(k));
            end

        catch
        end

    end

    uialert(app.UIFigure, ...
        ['Report generation failed:' newline newline ...
         ME.message], ...
        'Report Error', ...
        'Icon','error');

end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FeederInputTab = uitab(app.TabGroup);
            app.FeederInputTab.Title = 'Feeder Input';
            app.kVDISTRIBUTIONFEEDERANALYSISDESIGNTOOLTitleLabel_2 = uilabel(app.FeederInputTab);
            app.kVDISTRIBUTIONFEEDERANALYSISDESIGNTOOLTitleLabel_2.Text = '11-kV DISTRIBUTION FEEDER ANALYSIS & DESIGN TOOL';
            app.INPUTPARAMETERSLabel = uilabel(app.FeederInputTab);
            app.INPUTPARAMETERSLabel.Text = 'INPUT PARAMETERS';
            app.SystemVoltagekvEditFieldLabel = uilabel(app.FeederInputTab);
            app.SystemVoltagekvEditFieldLabel.Text = 'System Voltage(kv)';
            app.SystemVoltagekVEditField = uieditfield(app.FeederInputTab, 'numeric');
            app.SystemVoltagekVEditField.Value = 11;
            app.PowerFactorEditFieldLabel = uilabel(app.FeederInputTab);
            app.PowerFactorEditFieldLabel.Text = 'Power Factor';
            app.PowerFactorEditField = uieditfield(app.FeederInputTab, 'numeric');
            app.PowerFactorEditField.Value = 0.9;
            app.SectionLengthkmEditField = uieditfield(app.FeederInputTab, 'numeric');
            app.SectionLengthkmEditField.Value = 0.5;
            app.Load1kWEditFieldLabel = uilabel(app.FeederInputTab);
            app.Load1kWEditFieldLabel.Text = 'Load 1(kW)';
            app.Load1kWEditField = uieditfield(app.FeederInputTab, 'numeric');
            app.Load1kWEditField.Value = 100;
            app.Load2kWEditFieldLabel = uilabel(app.FeederInputTab);
            app.Load2kWEditFieldLabel.Text = 'Load 2(kW)';
            app.Load2kWEditField = uieditfield(app.FeederInputTab, 'numeric');
            app.Load2kWEditField.Value = 150;
            app.Load3kWEditFieldLabel = uilabel(app.FeederInputTab);
            app.Load3kWEditFieldLabel.Text = 'Load 3(kW)';
            app.Load3kWEditField = uieditfield(app.FeederInputTab, 'numeric');
            app.Load3kWEditField.Value = 200;
            app.Load4kWEditFieldLabel = uilabel(app.FeederInputTab);
            app.Load4kWEditFieldLabel.Text = 'Load 4(kW)';
            app.Load4kWEditField = uieditfield(app.FeederInputTab, 'numeric');
            app.Load4kWEditField.Value = 250;
            app.SectionLengthkmEditFieldLabel = uilabel(app.FeederInputTab);
            app.SectionLengthkmEditFieldLabel.Text = 'Section Length (km)';
            app.ANALYZEFEEDERButton = uibutton(app.FeederInputTab, 'push');
            app.ANALYZEFEEDERButton.ButtonPushedFcn = createCallbackFcn(app, @ANALYZEFEEDERButtonPushed, true);
            app.ANALYZEFEEDERButton.Text = 'ANALYZE FEEDER';
            app.ConductorParametersLabel = uilabel(app.FeederInputTab);
            app.ConductorParametersLabel.Text = 'Conductor Parameters';
            app.AmpacityALabel = uilabel(app.FeederInputTab);
            app.AmpacityALabel.Text = 'Ampacity (A)';
            app.RohmkmLabel = uilabel(app.FeederInputTab);
            app.RohmkmLabel.Text = 'R (ohm/km)';
            app.XohmkmLabel = uilabel(app.FeederInputTab);
            app.XohmkmLabel.Text = 'X (ohm/km)';
            app.ConductorAEditFieldLabel = uilabel(app.FeederInputTab);
            app.ConductorAEditFieldLabel.Text = 'Conductor A';
            app.ConductorAREditField = uieditfield(app.FeederInputTab, 'numeric');
            app.ConductorAREditField.Value = 0.3;
            app.ConductorAXEditField = uieditfield(app.FeederInputTab, 'numeric');
            app.ConductorAXEditField.Value = 0.35;
            app.ConductorBEditFieldLabel = uilabel(app.FeederInputTab);
            app.ConductorBEditFieldLabel.Text = 'Conductor B';
            app.ConductorBREditField = uieditfield(app.FeederInputTab, 'numeric');
            app.ConductorBREditField.Value = 0.22;
            app.ConductorBXEditField = uieditfield(app.FeederInputTab, 'numeric');
            app.ConductorBXEditField.Value = 0.34;
            app.ConductorCXEditField = uieditfield(app.FeederInputTab, 'numeric');
            app.ConductorCXEditField.Value = 0.32;
            app.ConductorCEditFieldLabel = uilabel(app.FeederInputTab);
            app.ConductorCEditFieldLabel.Text = 'Conductor C';
            app.ConductorCREditField = uieditfield(app.FeederInputTab, 'numeric');
            app.ConductorCREditField.Value = 0.15;
            app.ConductorAAmpacityEditField = uieditfield(app.FeederInputTab, 'numeric');
            app.ConductorAAmpacityEditField.Value = 100;
            app.ConductorBAmpacityEditField = uieditfield(app.FeederInputTab, 'numeric');
            app.ConductorBAmpacityEditField.Value = 125;
            app.ConductorCAmpacityEditField = uieditfield(app.FeederInputTab, 'numeric');
            app.ConductorCAmpacityEditField.Value = 160;
            app.AnalysisResultsTab = uitab(app.TabGroup);
            app.AnalysisResultsTab.Title = 'Analysis Results';
            app.VoltageProfileAxes = uiaxes(app.AnalysisResultsTab);
            title(app.VoltageProfileAxes, 'Voltage Profile')
            xlabel(app.VoltageProfileAxes, 'Distance From Substation(km)')
            ylabel(app.VoltageProfileAxes, 'Voltage (kV)')
            zlabel(app.VoltageProfileAxes, 'Z')
            app.PowerLossAxes = uiaxes(app.AnalysisResultsTab);
            title(app.PowerLossAxes, 'Power Loss By Section')
            xlabel(app.PowerLossAxes, 'Feeder Section')
            ylabel(app.PowerLossAxes, 'Power Loss(kW)')
            zlabel(app.PowerLossAxes, 'Z')
            app.kVFEEDERANALYSISRESULTSLabel = uilabel(app.AnalysisResultsTab);
            app.kVFEEDERANALYSISRESULTSLabel.Text = '11-kV FEEDER ANALYSIS RESULTS';
            app.PerformanceSummaryPanel = uipanel(app.AnalysisResultsTab);
            app.PerformanceSummaryPanel.Title = 'Performance Summary';
            app.TotalLoadkWEditFieldLabel = uilabel(app.PerformanceSummaryPanel);
            app.TotalLoadkWEditFieldLabel.Text = 'Total Load (kW)';
            app.TotalLoadkWEditField = uieditfield(app.PerformanceSummaryPanel, 'numeric');
            app.MaximumCurrentAEditFieldLabel = uilabel(app.PerformanceSummaryPanel);
            app.MaximumCurrentAEditFieldLabel.Text = 'Maximum Current (A)';
            app.MaximumCurrentAEditField = uieditfield(app.PerformanceSummaryPanel, 'numeric');
            app.MinimumVoltagekVEditFieldLabel = uilabel(app.PerformanceSummaryPanel);
            app.MinimumVoltagekVEditFieldLabel.Text = 'Minimum Voltage (kV)';
            app.MinimumVoltagekVEditField = uieditfield(app.PerformanceSummaryPanel, 'numeric');
            app.VoltageRegulationLabel = uilabel(app.PerformanceSummaryPanel);
            app.VoltageRegulationLabel.Text = 'Voltage Regulation (%)';
            app.VoltageRegulationEditField = uieditfield(app.PerformanceSummaryPanel, 'numeric');
            app.TotalPowerLosskWEditFieldLabel = uilabel(app.PerformanceSummaryPanel);
            app.TotalPowerLosskWEditFieldLabel.Text = 'Total Power Loss (kW)';
            app.TotalPowerLosskWEditField = uieditfield(app.PerformanceSummaryPanel, 'numeric');
            app.RecommendedConductorEditFieldLabel = uilabel(app.PerformanceSummaryPanel);
            app.RecommendedConductorEditFieldLabel.Text = 'Recommended Conductor';
            app.RecommendedConductorEditField = uieditfield(app.PerformanceSummaryPanel, 'text');
            app.RecommendedConductorEditField.Value = 'Not Analyzed';
            app.ConductorStatusEditFieldLabel = uilabel(app.PerformanceSummaryPanel);
            app.ConductorStatusEditFieldLabel.Text = 'Conductor Status';
            app.ConductorStatusEditField = uieditfield(app.PerformanceSummaryPanel, 'text');
            app.ConductorStatusEditField.Value = 'Not Analyzed';
            app.ConductorEvaluationTab = uitab(app.TabGroup);
            app.ConductorEvaluationTab.Title = 'Conductor Evaluation';
            app.ConductorEvaluationTable = uitable(app.ConductorEvaluationTab);
            app.ConductorEvaluationTable.ColumnName = {'Conductor'; 'Ampacity(A)'; 'Max Current(A)'; 'Voltage Regulation(%)'; 'Status'};
            app.ConductorEvaluationTable.RowName = {};
            app.ReportTab = uitab(app.TabGroup);
            app.ReportTab.Title = 'Report';
            app.DownloadReportButton = uibutton(app.ReportTab, 'push');
            app.DownloadReportButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadReportButtonPushed, true);
            app.DownloadReportButton.Text = 'Download Report';
            app.PerformanceSummaryTable = uitable(app.ReportTab);
            app.PerformanceSummaryTable.ColumnName = {'Parameter'; 'Result'};
            app.PerformanceSummaryTable.RowName = {};
            app.kVFeederReportLabel = uilabel(app.ReportTab);
            app.kVFeederReportLabel.Text = '11-kV Feeder Report';
            app.ReportConductorEvaluationTable = uitable(app.ReportTab);
            app.ReportConductorEvaluationTable.ColumnName = {'Conductor'; 'Ampacity(A)'; 'Max Current(A)'; 'Voltage Regulation(%)'; 'Status'};
            app.ReportConductorEvaluationTable.RowName = {};
            app.ConductorEvaluationLabel = uilabel(app.ReportTab);
            app.ConductorEvaluationLabel.Text = 'Conductor Evaluation';
            app.PerformanceSummaryLabel = uilabel(app.ReportTab);
            app.PerformanceSummaryLabel.Text = 'Performance Summary';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end